classdef labdeviceGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        MeasurementPanel               matlab.ui.container.Panel
        UIAxes                         matlab.ui.control.UIAxes
        UITable                        matlab.ui.control.Table
        CloseButton                    matlab.ui.control.Button
        SettingsPanel                  matlab.ui.container.Panel
        StartMeasurementButton         matlab.ui.control.Button
        Panel_5                        matlab.ui.container.Panel
        NormenButtonGroup              matlab.ui.container.ButtonGroup
        CISPRButton                    matlab.ui.control.RadioButton
        IECButton                      matlab.ui.control.RadioButton
        IMFButton                      matlab.ui.control.RadioButton
        StatusLampPanel                matlab.ui.container.Panel
        MeasLampLabel                  matlab.ui.control.Label
        MeasLamp                       matlab.ui.control.Lamp
        WaitLampLabel                  matlab.ui.control.Label
        WaitLamp                       matlab.ui.control.Lamp
        ReadyLampLabel                 matlab.ui.control.Label
        ReadyLamp                      matlab.ui.control.Lamp
        SelectMeasurementPanel         matlab.ui.container.TabGroup
        RadiatedEmissonTab             matlab.ui.container.Tab
        RE_InputParameterPanel         matlab.ui.container.Panel
        ProjectEditFieldLabel          matlab.ui.control.Label
        ProjectEditField               matlab.ui.control.EditField
        AntennapolDropDownLabel        matlab.ui.control.Label
        AntennapolDropDown             matlab.ui.control.DropDown
        StartfrequencyEditFieldLabel   matlab.ui.control.Label
        StartfrequencyEditField        matlab.ui.control.EditField
        StopfrequencyEditFieldLabel    matlab.ui.control.Label
        StopfrequencyEditField         matlab.ui.control.EditField
        DetectorDropDownLabel          matlab.ui.control.Label
        DetectorDropDown               matlab.ui.control.DropDown
        MeasurementTimeEditFieldLabel  matlab.ui.control.Label
        MeasurementTimeEditField       matlab.ui.control.EditField
        CommentEditFieldLabel          matlab.ui.control.Label
        CommentEditField               matlab.ui.control.EditField
        RE_EstablishConnectionPanel    matlab.ui.container.Panel
        ESRPConnectionSelectGroup      matlab.ui.container.ButtonGroup
        GPIBButton                     matlab.ui.control.RadioButton
        EthernetButton                 matlab.ui.control.RadioButton
        TestConnectionButton           matlab.ui.control.Button
        ConductedEmissionTab           matlab.ui.container.Tab
        SchirmdmpfungTab               matlab.ui.container.Tab
        OtherMeasurementTab            matlab.ui.container.Tab
    end

    
    properties (Access = private)
        Property % Description
        Project
        selecedCell
    end
    
    methods (Access = private)
        
        function setTableTitle(app)
           %app.Project = app.ProjectEditField;
            app.Label.Text = 'hallo';
        end
    
        function writeTable (app)
           %for idx = 1:numel(app.UITable)
           %app.Project = app.ProjectEditField.Value;
           d = {1, 'eins', 'asdf', 5, '', '', '', ''};
           app.UITable.Data = d;
           %end
           
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startup(app)
            
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            app.selectedCell = event.Indices;
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            %indices = event.Indices;
            %newData = event.NewData;
            d = {3,4,5,6,3,2,6,5;3,4,5,6,3,2,6,5;3,4,5,6,3,2,6,5;};
            app.UITable.Data = d;
            %editting the cell values doesn't work
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            selection = questdlg('U sure?', 'Confirmation', 'Yes','No','Yes');
            switch selection
                case 'Yes'
                    delete(app.UIFigure);
                case 'No'
                    return
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.MeasurementPanel = uipanel(app.UIFigure);
            app.UIAxes = uiaxes(app.MeasurementPanel);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'f/MHz')
            ylabel(app.UIAxes, 'U/(dBuV/m)')
            app.UIAxes.XTick = [0.1 1];
            app.UIAxes.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes.XScale = 'log';
            app.UITable = uitable(app.MeasurementPanel);
            app.UITable.ColumnName = {'Project'; 'Comment'; 'By'; 'To'; 'Measurement Time'; 'Detector'; 'Date'; 'Plot'};
            app.UITable.RowName = {''};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.CloseButton = uibutton(app.MeasurementPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.SettingsPanel = uipanel(app.UIFigure);
            app.StartMeasurementButton = uibutton(app.SettingsPanel, 'push');
            app.StartMeasurementButton.Text = {'Start'; 'Measurement'};
            app.Panel_5 = uipanel(app.SettingsPanel);
            app.NormenButtonGroup = uibuttongroup(app.Panel_5);
            app.NormenButtonGroup.Title = 'Normen';
            app.CISPRButton = uiradiobutton(app.NormenButtonGroup);
            app.CISPRButton.Text = 'CISPR';
            app.CISPRButton.Value = true;
            app.IECButton = uiradiobutton(app.NormenButtonGroup);
            app.IECButton.Text = 'IEC';
            app.IMFButton = uiradiobutton(app.NormenButtonGroup);
            app.IMFButton.Text = 'IMF';
            app.StatusLampPanel = uipanel(app.Panel_5);
            app.MeasLampLabel = uilabel(app.StatusLampPanel);
            app.MeasLampLabel.Text = 'Meas';
            app.MeasLamp = uilamp(app.StatusLampPanel);
            app.WaitLampLabel = uilabel(app.StatusLampPanel);
            app.WaitLampLabel.Text = 'Wait';
            app.WaitLamp = uilamp(app.StatusLampPanel);
            app.ReadyLampLabel = uilabel(app.StatusLampPanel);
            app.ReadyLampLabel.Text = 'Ready';
            app.ReadyLamp = uilamp(app.StatusLampPanel);
            app.SelectMeasurementPanel = uitabgroup(app.SettingsPanel);
            app.RadiatedEmissonTab = uitab(app.SelectMeasurementPanel);
            app.RadiatedEmissonTab.Title = 'Radiated Emisson';
            app.RE_InputParameterPanel = uipanel(app.RadiatedEmissonTab);
            app.ProjectEditFieldLabel = uilabel(app.RE_InputParameterPanel);
            app.ProjectEditFieldLabel.Text = 'Project';
            app.ProjectEditField = uieditfield(app.RE_InputParameterPanel, 'text');
            app.AntennapolDropDownLabel = uilabel(app.RE_InputParameterPanel);
            app.AntennapolDropDownLabel.Text = 'Antenna pol.';
            app.AntennapolDropDown = uidropdown(app.RE_InputParameterPanel);
            app.AntennapolDropDown.Items = {'horizontal', 'vertical'};
            app.AntennapolDropDown.Value = 'horizontal';
            app.StartfrequencyEditFieldLabel = uilabel(app.RE_InputParameterPanel);
            app.StartfrequencyEditFieldLabel.Text = 'Start frequency';
            app.StartfrequencyEditField = uieditfield(app.RE_InputParameterPanel, 'text');
            app.StopfrequencyEditFieldLabel = uilabel(app.RE_InputParameterPanel);
            app.StopfrequencyEditFieldLabel.Text = 'Stop frequency';
            app.StopfrequencyEditField = uieditfield(app.RE_InputParameterPanel, 'text');
            app.DetectorDropDownLabel = uilabel(app.RE_InputParameterPanel);
            app.DetectorDropDownLabel.Text = 'Detector';
            app.DetectorDropDown = uidropdown(app.RE_InputParameterPanel);
            app.DetectorDropDown.Items = {'qpeak', 'mpeak', 'aver'};
            app.DetectorDropDown.Value = 'qpeak';
            app.MeasurementTimeEditFieldLabel = uilabel(app.RE_InputParameterPanel);
            app.MeasurementTimeEditFieldLabel.Text = 'Measurement Time';
            app.MeasurementTimeEditField = uieditfield(app.RE_InputParameterPanel, 'text');
            app.CommentEditFieldLabel = uilabel(app.RE_InputParameterPanel);
            app.CommentEditFieldLabel.Text = 'Comment';
            app.CommentEditField = uieditfield(app.RE_InputParameterPanel, 'text');
            app.RE_EstablishConnectionPanel = uipanel(app.RadiatedEmissonTab);
            app.ESRPConnectionSelectGroup = uibuttongroup(app.RE_EstablishConnectionPanel);
            app.ESRPConnectionSelectGroup.Title = 'R&S ESRP';
            app.GPIBButton = uiradiobutton(app.ESRPConnectionSelectGroup);
            app.GPIBButton.Text = 'GPIB';
            app.GPIBButton.Value = true;
            app.EthernetButton = uiradiobutton(app.ESRPConnectionSelectGroup);
            app.EthernetButton.Text = 'Ethernet';
            app.TestConnectionButton = uibutton(app.RE_EstablishConnectionPanel, 'push');
            app.TestConnectionButton.Text = 'Test Connection';
            app.ConductedEmissionTab = uitab(app.SelectMeasurementPanel);
            app.ConductedEmissionTab.Title = 'Conducted Emission';
            app.SchirmdmpfungTab = uitab(app.SelectMeasurementPanel);
            app.SchirmdmpfungTab.Title = 'Schirmdämpfung';
            app.OtherMeasurementTab = uitab(app.SelectMeasurementPanel);
            app.OtherMeasurementTab.Title = 'Other Measurement';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = labdeviceGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startup)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end